classdef REPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        EnteryourdesiredareaLabel      matlab.ui.control.Label
        EnteryourdesiredareaEditField  matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        GetPredictionButton            matlab.ui.control.Button
        SelectyourcityLabel            matlab.ui.control.Label
        SelectyourcityDropDown         matlab.ui.control.DropDown
        ChooseMethodLabel              matlab.ui.control.Label
        ChooseMethodDropDown           matlab.ui.control.DropDown
        PredictedPriceKLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        Dhaka = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 601 612 660 697 710 753 802 832 876 908 958 1002 1030 1056 1087 1115 1163 1195 1240 1267 1289 1304 1319 1321];
        Feni = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 601 612 660 697 710 753 802 832 876 908 958 1002 1030 1056 1087 1115 1163 1195 1240 1267 1289 1304 1319 1321];
        Rajshahi = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 405 420 434 462 477 504 525 550 573 601 625 639 657 685 706 727 747 768 785 801 822 846 860 876];
        Chittagong = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 206 216 231 243 256 269 279 292 305 316 326 340 352 367 378 391 405 416 427 434 442 489 560 580];
        Rangpur = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 405 420 434 462 477 504 525 550 573 601 625 639 657 685 706 727 747 768 785 801 822 846 860 876];
        Sylhet = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 300 301 326 340 355 370 383 397 410 425 439 454 468 482 498 513 528 541 555 569 582 598 611 625];
        Barisal = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 500 530 557 580 610 635 663 690 712 741 762 790 815 836 863 893 923 950 974 1001 1020 1045 1070 1088];
        Khulna = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 500 530 557 580 610 635 663 690 712 741 762 790 815 836 863 893 923 950 974 1001 1020 1045 1070 1088];
        Mymensingh = [950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100; 300 301 326 340 355 370 383 397 410 425 439 454 468 482 498 513 528 541 555 569 582 598 611 625];
        selectedCity;
        method;
        desiredArea;
    end

    methods (Access = private)

        function plotFittedCurve(app)
            plot(app.UIAxes, [0 1 2 3], 'ob');
            hold(app.UIAxes);
        end

        function results = linearRegression(app)
            %             Reading Dataset
            dataSet = app.(app.selectedCity);
            price = dataSet(2, :);
            area = dataSet(1, :);

            a = polyfit(area,price, 1);
 
            ym=a(2)+a(1).*area;

            plot(app.UIAxes, area, price,'o')
            hold(app.UIAxes);
            plot(app.UIAxes,area,ym, '-r');

            results = a(2)+a(1).*app.desiredArea;
        end

        function results = interpolation(app)
            dataSet = app.(app.selectedCity);
            price = dataSet(2, :);
            area = dataSet(1, :);
            xi = linspace(950, 2100, 1000);  % Generating 1000 interpolation points between 950 and 2100

            % Perform cubic spline interpolation
            yi = interp1(area, price, xi, 'spline');


            % Plot the original data points and the interpolated curve
            plot(app.UIAxes, area, price, 'o', xi, yi);
            hold(app.UIAxes);
            grid(app.UIAxes);
            xlabel(app.UIAxes,'Area (Sq. Ft.)');
            ylabel(app.UIAxes, 'Price (K)');
            title(app.UIAxes,'Cubic Spline Interpolation Example');
            legend(app.UIAxes, 'Data Points', 'Interpolated Curve');
            results = interp1(area, price, app.desiredArea, 'spline');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectyourcityDropDown
        function SelectyourcityDropDownValueChanged(app, event)
            value = app.SelectyourcityDropDown.Value;
            app.selectedCity = string(value);
        end

        % Button pushed function: GetPredictionButton
        function GetPredictionButtonPushed(app, event)
            %             value = app.EnteryourdesiredareaEditField.Value;
            if string(app.method) == "Interpolation"
                res  = app.interpolation();
            else
                res = app.linearRegression();
            end
            app.PredictedPriceKLabel.Visible = "on";
            app.Label.Visible = "on";
            app.Label.Text = string(res);
            app.UIAxes.Visible = "on";            
        end

        % Value changed function: ChooseMethodDropDown
        function ChooseMethodDropDownValueChanged(app, event)
            value = app.ChooseMethodDropDown.Value;
            app.method = string(value);
        end

        % Value changed function: EnteryourdesiredareaEditField
        function EnteryourdesiredareaEditFieldValueChanged(app, event)
            value = app.EnteryourdesiredareaEditField.Value;
            app.desiredArea = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Get Prediction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.EnteryourdesiredareaLabel = uilabel(app.UIFigure);
            app.EnteryourdesiredareaLabel.Text = 'Enter your desired area ';
            app.EnteryourdesiredareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnteryourdesiredareaEditField.ValueChangedFcn = createCallbackFcn(app, @EnteryourdesiredareaEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.GetPredictionButton = uibutton(app.UIFigure, 'push');
            app.GetPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPredictionButtonPushed, true);
            app.GetPredictionButton.Text = 'Get Prediction';
            app.SelectyourcityLabel = uilabel(app.UIFigure);
            app.SelectyourcityLabel.Text = 'Select your city ';
            app.SelectyourcityDropDown = uidropdown(app.UIFigure);
            app.SelectyourcityDropDown.Items = {'Dhaka', 'Feni', 'Rajshahi', 'Chittagong', 'Rangpur', 'Sylhet', 'Barisal', 'Khulna', 'Mymensingh'};
            app.SelectyourcityDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectyourcityDropDownValueChanged, true);
            app.SelectyourcityDropDown.Value = 'Feni';
            app.ChooseMethodLabel = uilabel(app.UIFigure);
            app.ChooseMethodLabel.Text = 'Choose Method  ';
            app.ChooseMethodDropDown = uidropdown(app.UIFigure);
            app.ChooseMethodDropDown.Items = {'Linear Regression', 'Interpolation'};
            app.ChooseMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMethodDropDownValueChanged, true);
            app.ChooseMethodDropDown.Value = 'Linear Regression';
            app.PredictedPriceKLabel = uilabel(app.UIFigure);
            app.PredictedPriceKLabel.Visible = 'off';
            app.PredictedPriceKLabel.Text = 'Predicted Price (K)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end